classdef CoFlyersGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RadarDetectorPanel              matlab.ui.container.Panel
        Image_2                         matlab.ui.control.Image
        ConfigfileDropDown              matlab.ui.control.DropDown
        ConfigfileDropDownLabel         matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        SettingsTab                     matlab.ui.container.Tab
        TimeintervalofsavingstatesSpinner  matlab.ui.control.Spinner
        TimeintervalofsavingstatesSpinnerLabel  matlab.ui.control.Label
        DatasaveSwitch                  matlab.ui.control.Switch
        DatasaveSwitchLabel             matlab.ui.control.Label
        Lamp_data_save                  matlab.ui.control.Lamp
        BottomControlSamplingtimesEditField  matlab.ui.control.NumericEditField
        BottomControlSamplingtimesEditFieldLabel  matlab.ui.control.Label
        Label_equal_4                   matlab.ui.control.Label
        T_mtimesLabel_2                 matlab.ui.control.Label
        Times_Tb_Spinner                matlab.ui.control.Spinner
        Label_equal_3                   matlab.ui.control.Label
        T_mtimesLabel                   matlab.ui.control.Label
        T_mLabel                        matlab.ui.control.Label
        MotionSamplingtimesSpinner      matlab.ui.control.Spinner
        MotionSamplingtimesSpinnerLabel  matlab.ui.control.Label
        Times_Tu_Spinner                matlab.ui.control.Spinner
        UpperControlSamplingtimesEditField  matlab.ui.control.NumericEditField
        UpperControlSamplingtimesEditFieldLabel  matlab.ui.control.Label
        VehicletypeDropDown             matlab.ui.control.DropDown
        VehicletypeDropDownLabel        matlab.ui.control.Label
        EvaluationMetricsDropDown       matlab.ui.control.DropDown
        EvaluationMetricsDropDownLabel  matlab.ui.control.Label
        SwarmAlgorithmDropDown          matlab.ui.control.DropDown
        SwarmAlgorithmDropDownLabel     matlab.ui.control.Label
        NumberofagentsSpinner           matlab.ui.control.Spinner
        NumberofagentsSpinnerLabel      matlab.ui.control.Label
        SimulationtimessSpinner         matlab.ui.control.Spinner
        SimulationtimessSpinnerLabel    matlab.ui.control.Label
        VisualTab                       matlab.ui.container.Tab
        FiguresaveSwitch                matlab.ui.control.Switch
        FiguresaveSwitchLabel           matlab.ui.control.Label
        Lamp_video                      matlab.ui.control.Lamp
        VideosaveSwitch                 matlab.ui.control.Switch
        VideosaveSwitchLabel            matlab.ui.control.Label
        Lamp_video_2                    matlab.ui.control.Lamp
        TrajectoriesSwitch              matlab.ui.control.Switch
        TrajectoriesSwitchLabel         matlab.ui.control.Label
        Lamp_trajectory                 matlab.ui.control.Lamp
        FramerateSpinner                matlab.ui.control.Spinner
        FramerateSpinnerLabel           matlab.ui.control.Label
        TrajectorytimeinternalsSpinner  matlab.ui.control.Spinner
        TrajectorytimeinternalsSpinnerLabel  matlab.ui.control.Label
        AgentFollowSwitch               matlab.ui.control.Switch
        AgentFollowSwitchLabel          matlab.ui.control.Label
        Lamp_Follow                     matlab.ui.control.Lamp
        PlottimeintervalSpinner         matlab.ui.control.Spinner
        PlottimeintervalSpinnerLabel    matlab.ui.control.Label
        PlotDimensionDropDown           matlab.ui.control.DropDown
        PlotDimensionDropDownLabel      matlab.ui.control.Label
        PlotSwitch                      matlab.ui.control.Switch
        PlotSwitchLabel                 matlab.ui.control.Label
        Lamp_plot                       matlab.ui.control.Lamp
        SwarmTab                        matlab.ui.container.Tab
        swarmtypeEditField              matlab.ui.control.EditField
        swarmtypeEditFieldLabel         matlab.ui.control.Label
        swarmParamEditField_16          matlab.ui.control.EditField
        swarmParamEditField_16Label     matlab.ui.control.Label
        swarmParamEditField_15          matlab.ui.control.EditField
        swarmParamEditField_15Label     matlab.ui.control.Label
        swarmParamEditField_14          matlab.ui.control.EditField
        swarmParamEditField_14Label     matlab.ui.control.Label
        swarmParamEditField_13          matlab.ui.control.EditField
        swarmParamEditField_13Label     matlab.ui.control.Label
        swarmParamEditField_12          matlab.ui.control.EditField
        swarmParamEditField_12Label     matlab.ui.control.Label
        swarmParamEditField_11          matlab.ui.control.EditField
        swarmParamEditField_11Label     matlab.ui.control.Label
        swarmParamEditField_10          matlab.ui.control.EditField
        swarmParamEditField_10Label     matlab.ui.control.Label
        swarmParamEditField_9           matlab.ui.control.EditField
        swarmParamEditField_9Label      matlab.ui.control.Label
        swarmParamEditField_8           matlab.ui.control.EditField
        swarmParamEditField_8Label      matlab.ui.control.Label
        swarmParamEditField_7           matlab.ui.control.EditField
        swarmParamEditField_7Label      matlab.ui.control.Label
        swarmParamEditField_6           matlab.ui.control.EditField
        swarmParamEditField_6Label      matlab.ui.control.Label
        swarmParamEditField_5           matlab.ui.control.EditField
        swarmParamEditField_5Label      matlab.ui.control.Label
        swarmParamEditField_4           matlab.ui.control.EditField
        swarmParamEditField_4Label      matlab.ui.control.Label
        swarmParamEditField_3           matlab.ui.control.EditField
        swarmParamEditField_3Label      matlab.ui.control.Label
        swarmParamEditField_2           matlab.ui.control.EditField
        swarmParamEditField_2Label      matlab.ui.control.Label
        swarmParamEditField_1           matlab.ui.control.EditField
        swarmParamEditField_1Label      matlab.ui.control.Label
        EvaluationTab                   matlab.ui.container.Tab
        evaluationtypeEditField         matlab.ui.control.EditField
        evaluationtypeEditFieldLabel    matlab.ui.control.Label
        evaluationParamEditField_15     matlab.ui.control.EditField
        evaluationParamEditField_15Label  matlab.ui.control.Label
        evaluationParamEditField_16     matlab.ui.control.EditField
        evaluationParamEditField_16Label  matlab.ui.control.Label
        evaluationParamEditField_14     matlab.ui.control.EditField
        evaluationParamEditField_14Label  matlab.ui.control.Label
        evaluationParamEditField_13     matlab.ui.control.EditField
        evaluationParamEditField_13Label  matlab.ui.control.Label
        evaluationParamEditField_12     matlab.ui.control.EditField
        evaluationParamEditField_12Label  matlab.ui.control.Label
        evaluationParamEditField_11     matlab.ui.control.EditField
        evaluationParamEditField_11Label  matlab.ui.control.Label
        evaluationParamEditField_10     matlab.ui.control.EditField
        evaluationParamEditField_10Label  matlab.ui.control.Label
        evaluationParamEditField_9      matlab.ui.control.EditField
        evaluationParamEditField_9Label  matlab.ui.control.Label
        evaluationParamEditField_8      matlab.ui.control.EditField
        evaluationParamEditField_8Label  matlab.ui.control.Label
        evaluationParamEditField_7      matlab.ui.control.EditField
        evaluationParamEditField_7Label  matlab.ui.control.Label
        evaluationParamEditField_6      matlab.ui.control.EditField
        evaluationParamEditField_6Label  matlab.ui.control.Label
        evaluationParamEditField_5      matlab.ui.control.EditField
        evaluationParamEditField_5Label  matlab.ui.control.Label
        evaluationParamEditField_4      matlab.ui.control.EditField
        evaluationParamEditField_4Label  matlab.ui.control.Label
        evaluationParamEditField_3      matlab.ui.control.EditField
        evaluationParamEditField_3Label  matlab.ui.control.Label
        evaluationParamEditField_2      matlab.ui.control.EditField
        evaluationParamEditField_2Label  matlab.ui.control.Label
        evaluationParamEditField_1      matlab.ui.control.EditField
        evaluationParamEditField_1Label  matlab.ui.control.Label
        StartsimulationButton           matlab.ui.control.Button
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        xml_name   % Config file name, XML
        xml_dir_name
        params     % Config parameter from the XML file
    end
    
    methods (Access = private)
        
        function change_params_submodule(app,submodule_name)
            params_max = 16;
            str_len_max = 100;
            
            for i = 1:params_max
                app.([submodule_name,'ParamEditField_',num2str(i)]).Visible = false;
                app.([submodule_name,'ParamEditField_',num2str(i),'Label']).Visible = false;
            end

            if strcmp(submodule_name,'swarm')
                type_name = app.SwarmAlgorithmDropDown.Value;
                values_submodule = app.params.swarm.(type_name);
                
            elseif strcmp(submodule_name,'evaluation')
                type_name = app.EvaluationMetricsDropDown.Value;
                values_submodule = app.params.evaluation.(type_name);
            end
            app.([submodule_name,'typeEditField']).Value = type_name;
            field_names_submodule = fieldnames(app.params.(submodule_name).(type_name));
            count = 1;
            for i = 1:length(field_names_submodule)
                if count>params_max
                    break
                end
                name = field_names_submodule{i};
                v = values_submodule.(name);
                str_len = sum(strlength(string(v)),'all');
                if str_len<str_len_max
                    if numel(v)==1
                        str_value = string(v);
                    else
                        if ~ischar(v)
                            str_value = strcat('[',join(join(string(v),',',2)',';'),']');
                        else
                            str_value = strcat("'",join(join(string(v),',',2)',';'),"'");
                        end
                    end
                    app.([submodule_name,'ParamEditField_',num2str(i)]).Visible = true;
                    app.([submodule_name,'ParamEditField_',num2str(i),'Label']).Visible = true;
                    app.([submodule_name,'ParamEditField_',num2str(i)]).Value = str_value;
                    app.([submodule_name,'ParamEditField_',num2str(i),'Label']).Text = name;
                    count = count + 1;
                end
            end
            if count < params_max
                for i = count+1:params_max
                    app.([submodule_name,'ParamEditField_',num2str(i)]).Visible = false;
                    app.([submodule_name,'ParamEditField_',num2str(i),'Label']).Visible = false;
                end
            end
        end
        
        function set_gui_from_xml(app, xml_name)
            app.xml_name = xml_name;
            [~, ~, ~, params_coflyers, ~, ~] = read_parameter_xml(xml_name, [], false);
            app.params = params_coflyers;

            %===========================Settings==========================%
            app.NumberofagentsSpinner.Value   = app.params.number;
            app.SimulationtimessSpinner.Value = app.params.time_max;
            app.MotionSamplingtimesSpinner.Value = app.params.sample_time_motion;

            app.UpperControlSamplingtimesEditField.Value = app.params.sample_time_control_upper;
            app.Times_Tu_Spinner.Value = floor(app.UpperControlSamplingtimesEditField.Value/...
                app.params.sample_time_motion);

            app.BottomControlSamplingtimesEditField.Value = app.params.sample_time_control_bottom;
            app.Times_Tb_Spinner.Value = floor(app.BottomControlSamplingtimesEditField.Value/...
                app.params.sample_time_motion);

            if app.params.activate_save_states>0
                app.DatasaveSwitch.Value = 'On';
            else
                app.DatasaveSwitch.Value = 'Off';
            end

            app.TimeintervalofsavingstatesSpinner.Value = app.params.time_interval_save;

            field_names_swarm = fieldnames(app.params.swarm);
            app.SwarmAlgorithmDropDown.Items = field_names_swarm;
            % app.SwarmAlgorithmDropDown.Value = field_names_swarm{1};

            field_names_motion = fieldnames(app.params.motion);
            app.VehicletypeDropDown.Items = field_names_motion;
            % app.VehicletypeDropDown.Value = field_names_motion{1};

            field_names_eva = fieldnames(app.params.evaluation);
            app.EvaluationMetricsDropDown.Items = field_names_eva;
            % app.EvaluationMetricsDropDown.Value = field_names_eva{1};

            temp = app.params.motion_model_type;
            temp1 = strcmp(app.VehicletypeDropDown.Items,temp);
            ind = find(temp1);
            if ~isempty(ind)
                app.VehicletypeDropDown.Value = app.VehicletypeDropDown.Items{ind(1)};
            else
                app.VehicletypeDropDown.Value = app.VehicletypeDropDown.Items{1};
            end

            temp = app.params.swarm_algorithm_type;
            temp1 = strcmp(app.SwarmAlgorithmDropDown.Items,temp);
            ind = find(temp1);
            if ~isempty(ind)
                app.SwarmAlgorithmDropDown.Value = app.SwarmAlgorithmDropDown.Items{ind(1)};
            else
                app.SwarmAlgorithmDropDown.Value = app.SwarmAlgorithmDropDown.Items{1};
            end

            temp = app.params.evaluation_metric_type;
            temp1 = strcmp(app.EvaluationMetricsDropDown.Items,temp);
            ind = find(temp1);
            if ~isempty(ind)
                app.EvaluationMetricsDropDown.Value = app.EvaluationMetricsDropDown.Items{ind(1)};
            else
                app.EvaluationMetricsDropDown.Value = app.EvaluationMetricsDropDown.Items{1};
            end
            
            % %============================Visual===========================%
            %#1
            if app.params.visual.activate_plot > 0
                app.PlotSwitch.Value = 'On';
            else
                app.PlotSwitch.Value = 'Off';
            end
            app.PlotSwitchValueChanged();
            %#2
            app.PlottimeintervalSpinner.Value = app.params.visual.time_interval_plot;
            %#3
            if app.params.visual.activate_trajectory > 0
                app.TrajectoriesSwitch.Value = 'On';
            else
                app.TrajectoriesSwitch.Value = 'Off';
            end
            app.TrajectoriesSwitchValueChanged();
            %#4
            if app.params.visual.follow_agent > 0
                app.AgentFollowSwitch.Value = 'On';
            else
                app.AgentFollowSwitch.Value = 'Off';
            end
            app.AgentFollowSwitchValueChanged();
            %#5
            if app.params.visual.activate_save_figure > 0
                app.FiguresaveSwitch.Value = 'On';
            else
                app.FiguresaveSwitch.Value = 'Off';
            end
            app.FiguresaveSwitchValueChanged();
            %#6
            if app.params.visual.activate_save_video > 0
                app.VideosaveSwitch.Value = 'On';
            else
                app.VideosaveSwitch.Value = 'Off';
            end
            app.VideosaveSwitchValueChanged();
            %#7
            app.PlotDimensionDropDown.Value = strcat(num2str(app.params.visual.dim_visual),'D');
            %#8
            app.TrajectorytimeinternalsSpinner.Value = app.params.visual.time_interval_trajectory;
            %#9
            app.FramerateSpinner.Value = app.params.visual.video_speed;
            
            %============================Swarm============================%
            change_params_submodule(app,'swarm');
            %==========================Evaluation=========================%
            change_params_submodule(app,'evaluation');

            %==============================================================
        end
    end
    
    methods (Access = public)
        
        function results = get_all_param(app)
            results = struct();
            results.param_name_s  = [];
            results.param_value_s = [];
            
            % results.param_name_s: parameter names defined in
            % parameters.xml
            % results.param_value_s: strings of the parameter values
            %===========================Settings==========================%
            results.param_name_s  = [results.param_name_s, strcat("CoFlyers.","number")];
            results.param_value_s = [results.param_value_s,...
                string(app.NumberofagentsSpinner.Value)];
            results.param_name_s  = [results.param_name_s, strcat("CoFlyers.","time_max")];
            results.param_value_s = [results.param_value_s,...
                string(app.SimulationtimessSpinner.Value)];

            results.param_name_s  = [results.param_name_s, strcat("CoFlyers.","sample_time_motion")];
            results.param_value_s = [results.param_value_s,...
                string(app.MotionSamplingtimesSpinner.Value)];

            results.param_name_s  = [results.param_name_s, strcat("CoFlyers.","sample_time_control_upper")];
            results.param_value_s = [results.param_value_s,...
                string(app.UpperControlSamplingtimesEditField.Value)];

            results.param_name_s  = [results.param_name_s, strcat("CoFlyers.","sample_time_control_bottom")];
            results.param_value_s = [results.param_value_s,...
                string(app.BottomControlSamplingtimesEditField.Value)];

            results.param_name_s  = [results.param_name_s, strcat("CoFlyers.","activate_save_states")];
            results.param_value_s = [results.param_value_s,...
                string(double(strcmp(app.DatasaveSwitch.Value,'On')))];

            results.param_name_s  = [results.param_name_s, strcat("CoFlyers.","time_interval_save")];
            results.param_value_s = [results.param_value_s,...
                string(app.TimeintervalofsavingstatesSpinner.Value)];

            results.param_name_s  = [results.param_name_s, strcat("CoFlyers.","motion_model_type")];
            results.param_value_s = [results.param_value_s,...
                strcat("'",string(app.VehicletypeDropDown.Value),"'")];

            results.param_name_s  = [results.param_name_s, strcat("CoFlyers.","swarm_algorithm_type")];
            results.param_value_s = [results.param_value_s,...
                strcat("'",string(app.SwarmAlgorithmDropDown.Value),"'")];

            results.param_name_s  = [results.param_name_s, strcat("CoFlyers.","evaluation_metric_type")];
            results.param_value_s = [results.param_value_s,...
                strcat("'",string(app.EvaluationMetricsDropDown.Value),"'")];
            
            %============================Visual===========================%
            results.param_name_s  = [results.param_name_s, strcat("CoFlyers.visual.","activate_plot")];
            results.param_value_s = [results.param_value_s,...
                string(double(strcmp(app.PlotSwitch.Value,'On')))];

            results.param_name_s  = [results.param_name_s, strcat("CoFlyers.visual.","time_interval_plot")];
            results.param_value_s = [results.param_value_s,...
                string(app.PlottimeintervalSpinner.Value)];

            results.param_name_s  = [results.param_name_s, strcat("CoFlyers.visual.","activate_trajectory")];
            results.param_value_s = [results.param_value_s,...
                string(double(strcmp(app.TrajectoriesSwitch.Value,'On')))];

            results.param_name_s  = [results.param_name_s, strcat("CoFlyers.visual.","follow_agent")];
            results.param_value_s = [results.param_value_s,...
                string(double(strcmp(app.AgentFollowSwitch.Value,'On')))];
            
            results.param_name_s  = [results.param_name_s, strcat("CoFlyers.visual.","activate_save_figure")];
            results.param_value_s = [results.param_value_s,...
                string(double(strcmp(app.FiguresaveSwitch.Value,'On')))];
            
            results.param_name_s  = [results.param_name_s, strcat("CoFlyers.visual.","activate_save_video")];
            results.param_value_s = [results.param_value_s,...
                string(double(strcmp(app.VideosaveSwitch.Value,'On')))];
            
            results.param_name_s  = [results.param_name_s, strcat("CoFlyers.visual.","dim_visual")];
            results.param_value_s = [results.param_value_s,...
                string(app.PlotDimensionDropDown.Value(1))];
            
            results.param_name_s  = [results.param_name_s, strcat("CoFlyers.visual.","time_interval_trajectory")];
            results.param_value_s = [results.param_value_s,...
                string(app.TrajectorytimeinternalsSpinner.Value)];
            
            results.param_name_s  = [results.param_name_s, strcat("CoFlyers.visual.","video_speed")];
            results.param_value_s = [results.param_value_s,...
                string(app.FramerateSpinner.Value)];

            %============================Swarm===========================%
            len = length(app.SwarmTab.Children)/2;
            swarm_type = app.SwarmTab.Children(1).Value;
            for id = 1:len-1
                id_value = id*2+1;
                id_name  = id*2+2;
                if app.SwarmTab.Children(id_value).Visible
                    value = string(app.SwarmTab.Children(id_value).Value);
                    name  = string(app.SwarmTab.Children(id_name).Text);
                    results.param_name_s  = [results.param_name_s,...
                        strcat("CoFlyers.swarm.",swarm_type,".",name)];
                    results.param_value_s  = [results.param_value_s,value];
                end
            end
            %===========================Evaluation========================%
            len = length(app.EvaluationTab.Children)/2;
            evaluation_type = app.EvaluationTab.Children(1).Value;
            for id = 1:len-1
                id_value = id*2+1;
                id_name  = id*2+2;
                if app.EvaluationTab.Children(id_value).Visible
                    value = string(app.EvaluationTab.Children(id_value).Value);
                    name  = string(app.EvaluationTab.Children(id_name).Text);
                    results.param_name_s  = [results.param_name_s,...
                        strcat("CoFlyers.evaluation.",evaluation_type,".",name)];
                    results.param_value_s  = [results.param_value_s,value];
                end
            end
            %==============================================================
        end
        
        function xml_name_out = get_xml_name(app)
            xml_name_out = app.xml_name;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath(fileparts(mfilename('fullpath'))))
            % app.UIFigure.Name = 'CoFlyers Gui';
            app.UIFigure.Name = 'Gui simulation';
            %==========Get xml file===========%
            app.xml_dir_name = 'xml_config_files';
            file_s = dir([app.xml_dir_name,'/*.xml']);
            file_name_s = {file_s.name};
            app.ConfigfileDropDown.Items = file_name_s;
            app.ConfigfileDropDown.Value = file_name_s{1};
            app.xml_name = strcat(app.xml_dir_name,"/",file_name_s{1});
            %==================================
            app.set_gui_from_xml(app.xml_name);

            %==================================
            app.UIFigure.UserData = struct("flag_stop",false);
        end

        % Drop down opening function: ConfigfileDropDown
        function ConfigfileDropDownOpening(app, event)
            dir_name = 'xml_config_files';
            file_s = dir([dir_name,'/*.xml']);
            file_name_s = {file_s.name};
            app.ConfigfileDropDown.Items = file_name_s;
        end

        % Button pushed function: StartsimulationButton
        function StartsimulationButtonPushed(app, event)
            if strcmp(app.StartsimulationButton.Text,'Start simulation')
                cla(app.UIAxes,"reset")
                cla(app.UIAxes2,"reset")
                app.UIAxes.Tag  = "axis_swarm";
                app.UIAxes2.Tag = "axis_evaluation";
                app.UIAxes4.Tag = "blood";
                app.UIFigure.UserData.flag_stop = false;
                app.StartsimulationButton.Text = 'Stop simulation';
                main_rapid_prototyping();
                app.StartsimulationButton.Text = 'Start simulation';
            else
                app.StartsimulationButton.Text = 'Start simulation';
                app.UIFigure.UserData.flag_stop = true;
            end
        end

        % Value changing function: MotionSamplingtimesSpinner
        function MotionSamplingtimesSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.UpperControlSamplingtimesEditField.Value =...
                changingValue * app.Times_Tu_Spinner.Value;
            app.BottomControlSamplingtimesEditField.Value =...
                changingValue * app.Times_Tb_Spinner.Value;
        end

        % Value changing function: Times_Tu_Spinner
        function Times_Tu_SpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.UpperControlSamplingtimesEditField.Value =...
                changingValue * app.MotionSamplingtimesSpinner.Value;    
        end

        % Value changing function: Times_Tb_Spinner
        function Times_Tb_SpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.BottomControlSamplingtimesEditField.Value =...
                changingValue * app.MotionSamplingtimesSpinner.Value;
        end

        % Value changed function: SwarmAlgorithmDropDown
        function SwarmAlgorithmDropDownValueChanged(app, event)
            change_params_submodule(app,'swarm');
        end

        % Value changed function: EvaluationMetricsDropDown
        function EvaluationMetricsDropDownValueChanged(app, event)
            change_params_submodule(app,'evaluation');
        end

        % Value changed function: PlotSwitch
        function PlotSwitchValueChanged(app, event)
            value = app.PlotSwitch.Value;
            if strcmp(value,'Off')
                app.Lamp_plot.Color = [1,0,0];
            else
                app.Lamp_plot.Color = [0,1,0];
            end
        end

        % Value changed function: AgentFollowSwitch
        function AgentFollowSwitchValueChanged(app, event)
            value = app.AgentFollowSwitch.Value;
            if strcmp(value,'Off')
                app.Lamp_Follow.Color = [1,0,0];
            else
                app.Lamp_Follow.Color = [0,1,0];
            end
            
        end

        % Value changed function: FiguresaveSwitch
        function FiguresaveSwitchValueChanged(app, event)
            value = app.FiguresaveSwitch.Value;
            if strcmp(value,'Off')
                app.Lamp_video.Color = [1,0,0];
            else
                app.Lamp_video.Color = [0,1,0];
            end
            
        end

        % Value changed function: TrajectoriesSwitch
        function TrajectoriesSwitchValueChanged(app, event)
            value = app.TrajectoriesSwitch.Value;
            if strcmp(value,'Off')
                app.Lamp_trajectory.Color = [1,0,0];
            else
                app.Lamp_trajectory.Color = [0,1,0];
            end
        end

        % Value changed function: VideosaveSwitch
        function VideosaveSwitchValueChanged(app, event)
            value = app.VideosaveSwitch.Value;
            if strcmp(value,'Off')
                app.Lamp_video_2.Color = [1,0,0];
            else
                app.Lamp_video_2.Color = [0,1,0];
            end
        end

        % Value changed function: DatasaveSwitch
        function DatasaveSwitchValueChanged(app, event)
            value = app.DatasaveSwitch.Value;
            if strcmp(value,'Off')
                app.Lamp_data_save.Color = [1,0,0];
            else
                app.Lamp_data_save.Color = [0,1,0];
            end
        end

        % Value changed function: ConfigfileDropDown
        function ConfigfileDropDownValueChanged(app, event)
            value = app.ConfigfileDropDown.Value;
            app.set_gui_from_xml(strcat(app.xml_dir_name,"/",value));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.StartsimulationButton = uibutton(app.UIFigure, 'push');
            app.StartsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartsimulationButtonPushed, true);
            app.StartsimulationButton.Icon = fullfile(pathToMLAPP, 'upper_layer', 'GUI_function', 'icons', 'button_icon.png');
            app.StartsimulationButton.Text = 'Start simulation';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.SimulationtimessSpinnerLabel = uilabel(app.SettingsTab);
            app.SimulationtimessSpinnerLabel.Text = 'Simulation times (s)';
            app.SimulationtimessSpinner = uispinner(app.SettingsTab);
            app.SimulationtimessSpinner.Step = 10;
            app.SimulationtimessSpinner.Limits = [0 Inf];
            app.SimulationtimessSpinner.Value = 100;
            app.NumberofagentsSpinnerLabel = uilabel(app.SettingsTab);
            app.NumberofagentsSpinnerLabel.Text = 'Number of agents';
            app.NumberofagentsSpinner = uispinner(app.SettingsTab);
            app.NumberofagentsSpinner.Limits = [1 Inf];
            app.NumberofagentsSpinner.RoundFractionalValues = 'on';
            app.NumberofagentsSpinner.Value = 6;
            app.SwarmAlgorithmDropDownLabel = uilabel(app.SettingsTab);
            app.SwarmAlgorithmDropDownLabel.Text = 'Swarm Algorithm';
            app.SwarmAlgorithmDropDown = uidropdown(app.SettingsTab);
            app.SwarmAlgorithmDropDown.Items = {'Vasarhelyi', 'Vasarhelyi_will'};
            app.SwarmAlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @SwarmAlgorithmDropDownValueChanged, true);
            app.SwarmAlgorithmDropDown.Value = 'Vasarhelyi';
            app.EvaluationMetricsDropDownLabel = uilabel(app.SettingsTab);
            app.EvaluationMetricsDropDownLabel.Text = 'Evaluation Metrics';
            app.EvaluationMetricsDropDown = uidropdown(app.SettingsTab);
            app.EvaluationMetricsDropDown.Items = {'evaluation_0'};
            app.EvaluationMetricsDropDown.ValueChangedFcn = createCallbackFcn(app, @EvaluationMetricsDropDownValueChanged, true);
            app.EvaluationMetricsDropDown.Value = 'evaluation_0';
            app.VehicletypeDropDownLabel = uilabel(app.SettingsTab);
            app.VehicletypeDropDownLabel.Text = 'Vehicle type';
            app.VehicletypeDropDown = uidropdown(app.SettingsTab);
            app.VehicletypeDropDown.Items = {'point-mass', 'quadcopter'};
            app.VehicletypeDropDown.Value = 'point-mass';
            app.UpperControlSamplingtimesEditFieldLabel = uilabel(app.SettingsTab);
            app.UpperControlSamplingtimesEditFieldLabel.Text = 'Upper Control Sampling time (s)';
            app.UpperControlSamplingtimesEditField = uieditfield(app.SettingsTab, 'numeric');
            app.UpperControlSamplingtimesEditField.Editable = 'off';
            app.UpperControlSamplingtimesEditField.Value = 0.1;
            app.Times_Tu_Spinner = uispinner(app.SettingsTab);
            app.Times_Tu_Spinner.Step = 10;
            app.Times_Tu_Spinner.ValueChangingFcn = createCallbackFcn(app, @Times_Tu_SpinnerValueChanging, true);
            app.Times_Tu_Spinner.Limits = [1 Inf];
            app.Times_Tu_Spinner.RoundFractionalValues = 'on';
            app.Times_Tu_Spinner.Value = 40;
            app.MotionSamplingtimesSpinnerLabel = uilabel(app.SettingsTab);
            app.MotionSamplingtimesSpinnerLabel.Text = 'Motion Sampling time (s)';
            app.MotionSamplingtimesSpinner = uispinner(app.SettingsTab);
            app.MotionSamplingtimesSpinner.Step = 0.0025;
            app.MotionSamplingtimesSpinner.ValueChangingFcn = createCallbackFcn(app, @MotionSamplingtimesSpinnerValueChanging, true);
            app.MotionSamplingtimesSpinner.Limits = [0.0001 Inf];
            app.MotionSamplingtimesSpinner.Value = 0.0025;
            app.T_mLabel = uilabel(app.SettingsTab);
            app.T_mLabel.Interpreter = 'latex';
            app.T_mLabel.Text = '$T_m$\ = ';
            app.T_mtimesLabel = uilabel(app.SettingsTab);
            app.T_mtimesLabel.Interpreter = 'latex';
            app.T_mtimesLabel.Text = '=\ $T_m$\times';
            app.Label_equal_3 = uilabel(app.SettingsTab);
            app.Label_equal_3.Interpreter = 'latex';
            app.Label_equal_3.Text = '=';
            app.Times_Tb_Spinner = uispinner(app.SettingsTab);
            app.Times_Tb_Spinner.ValueChangingFcn = createCallbackFcn(app, @Times_Tb_SpinnerValueChanging, true);
            app.Times_Tb_Spinner.Limits = [1 Inf];
            app.Times_Tb_Spinner.RoundFractionalValues = 'on';
            app.Times_Tb_Spinner.Value = 4;
            app.T_mtimesLabel_2 = uilabel(app.SettingsTab);
            app.T_mtimesLabel_2.Interpreter = 'latex';
            app.T_mtimesLabel_2.Text = '=\ $T_m$\times';
            app.Label_equal_4 = uilabel(app.SettingsTab);
            app.Label_equal_4.Interpreter = 'latex';
            app.Label_equal_4.Text = '=';
            app.BottomControlSamplingtimesEditFieldLabel = uilabel(app.SettingsTab);
            app.BottomControlSamplingtimesEditFieldLabel.Text = 'Bottom Control Sampling time (s)';
            app.BottomControlSamplingtimesEditField = uieditfield(app.SettingsTab, 'numeric');
            app.BottomControlSamplingtimesEditField.Editable = 'off';
            app.BottomControlSamplingtimesEditField.Value = 0.01;
            app.Lamp_data_save = uilamp(app.SettingsTab);
            app.DatasaveSwitchLabel = uilabel(app.SettingsTab);
            app.DatasaveSwitchLabel.Text = 'Data save';
            app.DatasaveSwitch = uiswitch(app.SettingsTab, 'slider');
            app.DatasaveSwitch.ValueChangedFcn = createCallbackFcn(app, @DatasaveSwitchValueChanged, true);
            app.TimeintervalofsavingstatesSpinnerLabel = uilabel(app.SettingsTab);
            app.TimeintervalofsavingstatesSpinnerLabel.Text = 'Time interval of saving states';
            app.TimeintervalofsavingstatesSpinner = uispinner(app.SettingsTab);
            app.TimeintervalofsavingstatesSpinner.Step = 0.1;
            app.TimeintervalofsavingstatesSpinner.Limits = [0.1 Inf];
            app.TimeintervalofsavingstatesSpinner.Value = 1;
            app.VisualTab = uitab(app.TabGroup);
            app.VisualTab.Title = 'Visual';
            app.Lamp_plot = uilamp(app.VisualTab);
            app.PlotSwitchLabel = uilabel(app.VisualTab);
            app.PlotSwitchLabel.Text = 'Plot';
            app.PlotSwitch = uiswitch(app.VisualTab, 'slider');
            app.PlotSwitch.ValueChangedFcn = createCallbackFcn(app, @PlotSwitchValueChanged, true);
            app.PlotSwitch.Value = 'On';
            app.PlotDimensionDropDownLabel = uilabel(app.VisualTab);
            app.PlotDimensionDropDownLabel.Text = 'Plot Dimension';
            app.PlotDimensionDropDown = uidropdown(app.VisualTab);
            app.PlotDimensionDropDown.Items = {'2D', '3D'};
            app.PlotDimensionDropDown.Value = '2D';
            app.PlottimeintervalSpinnerLabel = uilabel(app.VisualTab);
            app.PlottimeintervalSpinnerLabel.Text = 'Plot time interval';
            app.PlottimeintervalSpinner = uispinner(app.VisualTab);
            app.PlottimeintervalSpinner.Step = 0.1;
            app.PlottimeintervalSpinner.Limits = [1e-05 Inf];
            app.PlottimeintervalSpinner.Value = 1;
            app.Lamp_Follow = uilamp(app.VisualTab);
            app.AgentFollowSwitchLabel = uilabel(app.VisualTab);
            app.AgentFollowSwitchLabel.Text = 'Agent Follow';
            app.AgentFollowSwitch = uiswitch(app.VisualTab, 'slider');
            app.AgentFollowSwitch.ValueChangedFcn = createCallbackFcn(app, @AgentFollowSwitchValueChanged, true);
            app.TrajectorytimeinternalsSpinnerLabel = uilabel(app.VisualTab);
            app.TrajectorytimeinternalsSpinnerLabel.Text = 'Trajectory time internal (s)';
            app.TrajectorytimeinternalsSpinner = uispinner(app.VisualTab);
            app.TrajectorytimeinternalsSpinner.Limits = [0 Inf];
            app.TrajectorytimeinternalsSpinner.Value = 20;
            app.FramerateSpinnerLabel = uilabel(app.VisualTab);
            app.FramerateSpinnerLabel.Text = 'Frame rate';
            app.FramerateSpinner = uispinner(app.VisualTab);
            app.FramerateSpinner.Limits = [1 1000];
            app.FramerateSpinner.RoundFractionalValues = 'on';
            app.FramerateSpinner.Value = 30;
            app.Lamp_trajectory = uilamp(app.VisualTab);
            app.TrajectoriesSwitchLabel = uilabel(app.VisualTab);
            app.TrajectoriesSwitchLabel.Text = 'Trajectories';
            app.TrajectoriesSwitch = uiswitch(app.VisualTab, 'slider');
            app.TrajectoriesSwitch.ValueChangedFcn = createCallbackFcn(app, @TrajectoriesSwitchValueChanged, true);
            app.Lamp_video_2 = uilamp(app.VisualTab);
            app.VideosaveSwitchLabel = uilabel(app.VisualTab);
            app.VideosaveSwitchLabel.Text = 'Video save';
            app.VideosaveSwitch = uiswitch(app.VisualTab, 'slider');
            app.VideosaveSwitch.ValueChangedFcn = createCallbackFcn(app, @VideosaveSwitchValueChanged, true);
            app.Lamp_video = uilamp(app.VisualTab);
            app.FiguresaveSwitchLabel = uilabel(app.VisualTab);
            app.FiguresaveSwitchLabel.Text = 'Figure save';
            app.FiguresaveSwitch = uiswitch(app.VisualTab, 'slider');
            app.FiguresaveSwitch.ValueChangedFcn = createCallbackFcn(app, @FiguresaveSwitchValueChanged, true);
            app.SwarmTab = uitab(app.TabGroup);
            app.SwarmTab.Title = 'Swarm';
            app.swarmParamEditField_1Label = uilabel(app.SwarmTab);
            app.swarmParamEditField_1Label.Text = 'swarmParam';
            app.swarmParamEditField_1 = uieditfield(app.SwarmTab, 'text');
            app.swarmParamEditField_2Label = uilabel(app.SwarmTab);
            app.swarmParamEditField_2Label.Text = 'swarmParam';
            app.swarmParamEditField_2 = uieditfield(app.SwarmTab, 'text');
            app.swarmParamEditField_3Label = uilabel(app.SwarmTab);
            app.swarmParamEditField_3Label.Text = 'swarmParam';
            app.swarmParamEditField_3 = uieditfield(app.SwarmTab, 'text');
            app.swarmParamEditField_4Label = uilabel(app.SwarmTab);
            app.swarmParamEditField_4Label.Text = 'swarmParam';
            app.swarmParamEditField_4 = uieditfield(app.SwarmTab, 'text');
            app.swarmParamEditField_5Label = uilabel(app.SwarmTab);
            app.swarmParamEditField_5Label.Text = 'swarmParam';
            app.swarmParamEditField_5 = uieditfield(app.SwarmTab, 'text');
            app.swarmParamEditField_6Label = uilabel(app.SwarmTab);
            app.swarmParamEditField_6Label.Text = 'swarmParam';
            app.swarmParamEditField_6 = uieditfield(app.SwarmTab, 'text');
            app.swarmParamEditField_7Label = uilabel(app.SwarmTab);
            app.swarmParamEditField_7Label.Text = 'swarmParam';
            app.swarmParamEditField_7 = uieditfield(app.SwarmTab, 'text');
            app.swarmParamEditField_8Label = uilabel(app.SwarmTab);
            app.swarmParamEditField_8Label.Text = 'swarmParam';
            app.swarmParamEditField_8 = uieditfield(app.SwarmTab, 'text');
            app.swarmParamEditField_9Label = uilabel(app.SwarmTab);
            app.swarmParamEditField_9Label.Text = 'swarmParam';
            app.swarmParamEditField_9 = uieditfield(app.SwarmTab, 'text');
            app.swarmParamEditField_10Label = uilabel(app.SwarmTab);
            app.swarmParamEditField_10Label.Text = 'swarmParam';
            app.swarmParamEditField_10 = uieditfield(app.SwarmTab, 'text');
            app.swarmParamEditField_11Label = uilabel(app.SwarmTab);
            app.swarmParamEditField_11Label.Text = 'swarmParam';
            app.swarmParamEditField_11 = uieditfield(app.SwarmTab, 'text');
            app.swarmParamEditField_12Label = uilabel(app.SwarmTab);
            app.swarmParamEditField_12Label.Text = 'swarmParam';
            app.swarmParamEditField_12 = uieditfield(app.SwarmTab, 'text');
            app.swarmParamEditField_13Label = uilabel(app.SwarmTab);
            app.swarmParamEditField_13Label.Text = 'swarmParam';
            app.swarmParamEditField_13 = uieditfield(app.SwarmTab, 'text');
            app.swarmParamEditField_14Label = uilabel(app.SwarmTab);
            app.swarmParamEditField_14Label.Text = 'swarmParam';
            app.swarmParamEditField_14 = uieditfield(app.SwarmTab, 'text');
            app.swarmParamEditField_15Label = uilabel(app.SwarmTab);
            app.swarmParamEditField_15Label.Text = 'swarmParam';
            app.swarmParamEditField_15 = uieditfield(app.SwarmTab, 'text');
            app.swarmParamEditField_16Label = uilabel(app.SwarmTab);
            app.swarmParamEditField_16Label.Text = 'swarmParam';
            app.swarmParamEditField_16 = uieditfield(app.SwarmTab, 'text');
            app.swarmtypeEditFieldLabel = uilabel(app.SwarmTab);
            app.swarmtypeEditFieldLabel.Text = 'swarm type';
            app.swarmtypeEditField = uieditfield(app.SwarmTab, 'text');
            app.swarmtypeEditField.Editable = 'off';
            app.EvaluationTab = uitab(app.TabGroup);
            app.EvaluationTab.Title = 'Evaluation';
            app.evaluationParamEditField_1Label = uilabel(app.EvaluationTab);
            app.evaluationParamEditField_1Label.Text = 'evaluationParam';
            app.evaluationParamEditField_1 = uieditfield(app.EvaluationTab, 'text');
            app.evaluationParamEditField_2Label = uilabel(app.EvaluationTab);
            app.evaluationParamEditField_2Label.Text = 'evaluationParam';
            app.evaluationParamEditField_2 = uieditfield(app.EvaluationTab, 'text');
            app.evaluationParamEditField_3Label = uilabel(app.EvaluationTab);
            app.evaluationParamEditField_3Label.Text = 'evaluationParam';
            app.evaluationParamEditField_3 = uieditfield(app.EvaluationTab, 'text');
            app.evaluationParamEditField_4Label = uilabel(app.EvaluationTab);
            app.evaluationParamEditField_4Label.Text = 'evaluationParam';
            app.evaluationParamEditField_4 = uieditfield(app.EvaluationTab, 'text');
            app.evaluationParamEditField_5Label = uilabel(app.EvaluationTab);
            app.evaluationParamEditField_5Label.Text = 'evaluationParam';
            app.evaluationParamEditField_5 = uieditfield(app.EvaluationTab, 'text');
            app.evaluationParamEditField_6Label = uilabel(app.EvaluationTab);
            app.evaluationParamEditField_6Label.Text = 'evaluationParam';
            app.evaluationParamEditField_6 = uieditfield(app.EvaluationTab, 'text');
            app.evaluationParamEditField_7Label = uilabel(app.EvaluationTab);
            app.evaluationParamEditField_7Label.Text = 'evaluationParam';
            app.evaluationParamEditField_7 = uieditfield(app.EvaluationTab, 'text');
            app.evaluationParamEditField_8Label = uilabel(app.EvaluationTab);
            app.evaluationParamEditField_8Label.Text = 'evaluationParam';
            app.evaluationParamEditField_8 = uieditfield(app.EvaluationTab, 'text');
            app.evaluationParamEditField_9Label = uilabel(app.EvaluationTab);
            app.evaluationParamEditField_9Label.Text = 'evaluationParam';
            app.evaluationParamEditField_9 = uieditfield(app.EvaluationTab, 'text');
            app.evaluationParamEditField_10Label = uilabel(app.EvaluationTab);
            app.evaluationParamEditField_10Label.Text = 'evaluationParam';
            app.evaluationParamEditField_10 = uieditfield(app.EvaluationTab, 'text');
            app.evaluationParamEditField_11Label = uilabel(app.EvaluationTab);
            app.evaluationParamEditField_11Label.Text = 'evaluationParam';
            app.evaluationParamEditField_11 = uieditfield(app.EvaluationTab, 'text');
            app.evaluationParamEditField_12Label = uilabel(app.EvaluationTab);
            app.evaluationParamEditField_12Label.Text = 'evaluationParam';
            app.evaluationParamEditField_12 = uieditfield(app.EvaluationTab, 'text');
            app.evaluationParamEditField_13Label = uilabel(app.EvaluationTab);
            app.evaluationParamEditField_13Label.Text = 'evaluationParam';
            app.evaluationParamEditField_13 = uieditfield(app.EvaluationTab, 'text');
            app.evaluationParamEditField_14Label = uilabel(app.EvaluationTab);
            app.evaluationParamEditField_14Label.Text = 'evaluationParam';
            app.evaluationParamEditField_14 = uieditfield(app.EvaluationTab, 'text');
            app.evaluationParamEditField_16Label = uilabel(app.EvaluationTab);
            app.evaluationParamEditField_16Label.Text = 'evaluationParam';
            app.evaluationParamEditField_16 = uieditfield(app.EvaluationTab, 'text');
            app.evaluationParamEditField_15Label = uilabel(app.EvaluationTab);
            app.evaluationParamEditField_15Label.Text = 'evaluationParam';
            app.evaluationParamEditField_15 = uieditfield(app.EvaluationTab, 'text');
            app.evaluationtypeEditFieldLabel = uilabel(app.EvaluationTab);
            app.evaluationtypeEditFieldLabel.Text = 'evaluation type';
            app.evaluationtypeEditField = uieditfield(app.EvaluationTab, 'text');
            app.evaluationtypeEditField.Editable = 'off';
            app.ConfigfileDropDownLabel = uilabel(app.UIFigure);
            app.ConfigfileDropDownLabel.Text = 'Config file';
            app.ConfigfileDropDown = uidropdown(app.UIFigure);
            app.ConfigfileDropDown.Items = {'parameters.xml'};
            app.ConfigfileDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ConfigfileDropDownOpening, true);
            app.ConfigfileDropDown.ValueChangedFcn = createCallbackFcn(app, @ConfigfileDropDownValueChanged, true);
            app.ConfigfileDropDown.Value = 'parameters.xml';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'upper_layer', 'GUI_function', 'icons', 'CoFlyers_Logo.png');
            app.RadarDetectorPanel = uipanel(app.UIFigure);
            app.RadarDetectorPanel.Title = 'Radar Detector';
            app.RadarDetectorPanel.Tag = 'axis_swarm_sub';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CoFlyersGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end